classdef intersectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrafficIntersectionControlLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RegularControlTab               matlab.ui.container.Tab
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ScenarioSelectionButtonGroup    matlab.ui.container.ButtonGroup
        ScenarioAButton                 matlab.ui.control.ToggleButton
        ScenarioBButton                 matlab.ui.control.ToggleButton
        StatusLabel                     matlab.ui.control.Label
        DiagnosticTab                   matlab.ui.container.Tab
        StartButton_2                   matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        SpeedButtonGroup                matlab.ui.container.ButtonGroup
        speed_1x                        matlab.ui.control.RadioButton
        speed_3x                        matlab.ui.control.RadioButton
        speed_5x                        matlab.ui.control.RadioButton
        TrafficLightsSwitchLabel        matlab.ui.control.Label
        TrafficLightsSwitch             matlab.ui.control.RockerSwitch
        CrosswalksSwitchLabel           matlab.ui.control.Label
        CrosswalksSwitch                matlab.ui.control.RockerSwitch
        AmbientLightSwitchLabel         matlab.ui.control.Label
        AmbientLightSwitch              matlab.ui.control.RockerSwitch
        TurnLanesSwitchLabel            matlab.ui.control.Label
        TurnLanesSwitch                 matlab.ui.control.RockerSwitch
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeField                       matlab.ui.control.NumericEditField
        SensorsPanel                    matlab.ui.container.Panel
        CarinTLALampLabel               matlab.ui.control.Label
        CarinTLALamp                    matlab.ui.control.Lamp
        CarinTLCLampLabel               matlab.ui.control.Label
        CarinTLCLamp                    matlab.ui.control.Lamp
        NSCWPressLampLabel              matlab.ui.control.Label
        NSCWPressLamp                   matlab.ui.control.Lamp
        EWCWPressLampLabel              matlab.ui.control.Label
        EWCWPressLamp                   matlab.ui.control.Lamp
        LightIndicatorsPanel            matlab.ui.container.Panel
        LightALampLabel                 matlab.ui.control.Label
        LightALamp                      matlab.ui.control.Lamp
        LightBLampLabel                 matlab.ui.control.Label
        LightBLamp                      matlab.ui.control.Lamp
        LightCLampLabel                 matlab.ui.control.Label
        LightCLamp                      matlab.ui.control.Lamp
        LightDLampLabel                 matlab.ui.control.Label
        LightDLamp                      matlab.ui.control.Lamp
        LightELampLabel                 matlab.ui.control.Label
        LightELamp                      matlab.ui.control.Lamp
        NSCWLabel                       matlab.ui.control.Label
        NSCrosswalkStatus               matlab.ui.control.Lamp
        EWCWLabel                       matlab.ui.control.Label
        EWCrosswalkStatus               matlab.ui.control.Lamp
        LuxEditFieldLabel               matlab.ui.control.Label
        LuxField                        matlab.ui.control.NumericEditField
        CrosswalkActivationStatusPanel  matlab.ui.container.Panel
        NSCWActivatedLampLabel          matlab.ui.control.Label
        NSCWActivatedLamp               matlab.ui.control.Lamp
        EWCWActivatedLampLabel          matlab.ui.control.Label
        EWCWActivatedLamp               matlab.ui.control.Lamp
        ScenarioSelectionButtonGroup_2  matlab.ui.container.ButtonGroup
        ScenarioAButton_2               matlab.ui.control.ToggleButton
        ScenarioBButton_2               matlab.ui.control.ToggleButton
        DayNightLampLabel               matlab.ui.control.Label
        DayNightLamp                    matlab.ui.control.Lamp
        StatusLabel_2                   matlab.ui.control.Label
    end


    properties (Access = private)
        s % Speed
        stop % tells function to stop
        scenario % scenario
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear
            clc
            app.ScenarioAButton.Value = 1;
            app.ScenarioBButton.Value = 0;
            app.speed_1x.Value = 1;
            app.speed_3x.Value = 0;
            app.speed_5x.Value = 0;
            app.TrafficLightsSwitch.Value = 'On';
            app.CrosswalksSwitch.Value = 'On';
            app.AmbientLightSwitch.Value = 'On';
            app.TurnLanesSwitch.Value = 'On';
            app.ScenarioAButton_2.Value = 1;
            app.ScenarioBButton_2.Value = 0;
            app.s = 1;
            app.stop = 0;
            app.scenario = 'a';
            app.StatusLabel.Text = 'Initialized...';
            app.StatusLabel_2.Text = 'Initialized...';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.stop = 0;
            initializeAll(app.s);
             while app.stop == 0
                 pause(0.01);
                 app.StatusLabel.Text = 'Running...';
                 if app.ScenarioAButton.Value == 1
                    trafficArray = trafficLights();
                    carSense = turnLanes();
                    cwStates = crossWalkLights();
                    clickStates = crossWalkButtons();
                    lux = ambientLight();
                 else
                    trafficArray = trafficLightsB();
                    carSense = turnLanesB();
                    cwStates = crossWalkLightsB();
                    clickStates = crossWalkButtonsB();
                    lux = ambientLight();
                end
             end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StatusLabel.Text = 'Stopped...';
            turnOffLights();
            app.stop = 1;
            return;
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            app.stop = 0;
            if app.s == 1
                app.StatusLabel_2.Text = 'Running at 1X Speed';
            elseif app.s == 1
                app.StatusLabel_2.Text = 'Running at 3X Speed';
            elseif app.s == 1
                app.StatusLabel_2.Text = 'Running at 5X Speed';
            end
            
%            initializeAll(app.s);
            
            % Call global variables for indicators
%            global readyNS
%            global readyEW
            
            while app.stop == 0
                pause(0.01);                
                t = gtime();
                
                % Turn on traffic lights if switch is on.
                if strcmp(app.TrafficLightsSwitch.Value,'On') == 1
                    if app.ScenarioAButton_2.Value == 1
                        trafficArray = trafficLights();
                    else
                        trafficArray = trafficLightsB();
                    end
                    
                    % Enable traffic lights indicators and look at states
                    if trafficArray(1) == 1
                        app.LightALamp.Color = 'red';
                    elseif trafficArray(2) == 1
                        app.LightALamp.Color = 'yellow';
                    elseif trafficArray(3) == 1
                        app.LightALamp.Color = 'green';
                    else
                        app.LightALamp.Color = 'white';
                    end
                    
                    if trafficArray(4) == 1
                        app.LightBLamp.Color = 'red';
                    elseif trafficArray(5) == 1
                        app.LightBLamp.Color = 'yellow';
                    elseif trafficArray(6) == 1
                        app.LightBLamp.Color = 'green';
                    else
                        app.LightALamp.Color = 'white';
                    end
                    
                    if trafficArray(7) == 1
                        app.LightCLamp.Color = 'red';
                    elseif trafficArray(8) == 1
                        app.LightCLamp.Color = 'yellow';
                    elseif trafficArray(9) == 1
                        app.LightCLamp.Color = 'green';
                    else
                        app.LightALamp.Color = 'white';
                    end
                    
                    if trafficArray(10) == 1
                        app.LightDLamp.Color = 'red';
                    elseif trafficArray(11) == 1
                        app.LightDLamp.Color = 'yellow';
                    elseif trafficArray(12) == 1
                        app.LightDLamp.Color = 'green';
                    else
                        app.LightDLamp.Color = 'white';
                    end
                    
                    if trafficArray(13) == 1
                        app.LightELamp.Color = 'red';
                    elseif trafficArray(14) == 1
                        app.LightELamp.Color = 'yellow';
                    elseif trafficArray(15) == 1
                        app.LightELamp.Color = 'green';
                    else
                        app.LightELamp.Color = 'white';
                    end
                
                % Turn on Turn Lane sensors scripts if switch on.
                elseif strcmp(app.TurnLanesSwitch,'On') == 1
                    if app.ScenarioAButton.Value == 1
                        carSense = turnLanes();
                        if t > 51
                            gtreset();
                        end
                    else
                        carSense = turnLanesB();
                        if t > 60
                            gtreset();
                        end
                    end
                    
                    % Code for TL indicators
                    if carSense(1) == 1
                        app.CarinTLALamp.Color = 'green';
                    else
                        app.CarinTLALamp.Color = 'red';
                    end
                                        
                    if carSense(2) == 1
                        app.CarinTLCLamp.Color = 'green'
                    else
                        app.CarinTLCLamp.Color = 'red'
                    end
                    
                    % Crosswalk Indicators
                elseif strcmp(app.CrosswalksSwitch.Value,'On')
                    if app.ScenarioAButton.Value == 1
                        cwStates = crossWalkLights();
                        clickStates = crossWalkButtons();
                        if t > 51
                            gtreset();
                        end
                    else
                        cwStates = crossWalkLightsB();
                        clickStates = crossWalkButtonsB();
                        if t > 60
                            gtreset();
                        end
                    end
                    
                    % Indicator that button is pressed
                    if clickStates(1) == 0
                        app.NSCWPressLamp.Color = 'green';
                    else
                        app.NSCWPressLamp.Color = 'red';
                    end
                    
                    if clickStates(2) == 0
                        app.EWCWPressLamp.Color = 'green';
                    else
                        app.EWCWPressLamp.Color = 'red';
                    end
                    
                    % Indicators for Activation Status of CWs.
                    if readyNS == 1
                        app.NSCWActivatedLamp.Color = 'green';
                    else
                        app.NSCWActivatedLamp.Color = 'red';
                    end
                    
                    if readyEW == 1
                        app.EWCWActivatedLamp.Color = 'green'
                    else
                        app.EWCWActivatedLamp.Color = 'red'
                    end
                    
                    % Indicators for Current CW status
                    if cwStates(1:2) == [1 0]
                        app.NSCrosswalkStatus.Color = 'green';
                    elseif cwStates(1:2) == [0 1]
                        app.NSCrosswalkStatus.Color = 'red';
                    else
                        app.NSCrosswalkStatus.Color = 'white';
                    end
                    
                    if cwStates(3:4) == [1 0]
                        app.EWCrosswalkStatus.Color = 'green';
                    elseif cwStates(3:4) == [0 1]
                        app.EWCrosswalkStatus.Color = 'red';
                    else
                        app.EWCrosswalkStatus.Color = 'white';
                    end
                    
                elseif strcmp(app.AmbientLightSwitch,'On') == 1
                    [lux night] = ambientLight();
                    app.LuxField.Value = lux;
                    if night == 1
                        app.DayNightLamp.Color = 'black';
                    else
                        app.DayNightLamp.Color = 'yellow';
                    end
                    
                else
                    return;
                end
                
                %Display on GUI
                %t = double(t);
                %app.TimeField.Value = t;
            end
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            turnOffLights();
            app.StatusLabel_2.Text = 'Stopped';
            app.stop = 1;
            return;
        end

        % Selection changed function: SpeedButtonGroup
        function SpeedButtonGroupSelectionChanged(app, event)
            selectedButton = app.SpeedButtonGroup.SelectedObject;
            switch selectedButton
                case app.speed_1x
                    app.s = 1;
                case app.speed_3x
                    app.s = 3;
                case app.speed_5x
                    app.s = 5;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Selection changed function: ScenarioSelectionButtonGroup
        function ScenarioSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScenarioSelectionButtonGroup.SelectedObject;
            switch selectedButton
                case app.ScenarioAButton
                    app.scenario = 'a';
                case app.ScenarioBButton
                    app.scenario = 'b';
            end
        end

        % Value changed function: TrafficLightsSwitch
        function TrafficLightsSwitchValueChanged(app, event)
            value = app.TrafficLightsSwitch.Value;
            if strcmp(value,'Off') == 1
                % Deactivate relevant lamps
                app.LightALamp.Enable = 'off';
                app.LightBLamp.Enable = 'off';
                app.LightCLamp.Enable = 'off';
                app.LightDLamp.Enable = 'off';
                app.LightELamp.Enable = 'off';
            else
                app.LightALamp.Enable = 'on';
                app.LightBLamp.Enable = 'on';
                app.LightCLamp.Enable = 'on';
                app.LightDLamp.Enable = 'on';
                app.LightELamp.Enable = 'on';
            end

        end

        % Value changed function: CrosswalksSwitch
        function CrosswalksSwitchValueChanged(app, event)
            value = app.CrosswalksSwitch.Value;
            if strcmp(value,'Off') == 1
              % Deactivate relevant lamps
              app.NSCrosswalkStatus.Enable = 'off';
              app.EWCrosswalkStatus.Enable = 'off';
              app.NSCWActivatedLamp.Enable = 'off';
              app.EWCWActivatedLamp.Enable = 'off';
              app.NSCWPressLamp.Enable = 'off';
              app.EWCWPressLamp.Enable = 'off';
            else
              app.NSCrosswalkStatus.Enable = 'on';
              app.EWCrosswalkStatus.Enable = 'on';
              app.NSCWActivatedLamp.Enable = 'on';
              app.EWCWActivatedLamp.Enable = 'on';
              app.NSCWPressLamp.Enable = 'on';
              app.EWCWPressLamp.Enable = 'on';
            end
        end

        % Value changed function: AmbientLightSwitch
        function AmbientLightSwitchValueChanged(app, event)
            value = app.AmbientLightSwitch.Value;
            if strcmp(value,'Off') == 1
                app.LuxField.Enable = 'off';
                app.DayNightLamp.Enable = 'off';
            else
                app.LuxField.Enable = 'on';
                app.DayNightLamp.Enable = 'on';
            end
        end

        % Value changed function: TurnLanesSwitch
        function TurnLanesSwitchValueChanged(app, event)
            value = app.TurnLanesSwitch.Value;
            if strcmp(value,'Off') == 1
                app.CarinTLALamp.Enable = 'off';
                app.CarinTLCLamp.Enable = 'off';
            else
                app.CarinTLALamp.Enable = 'on';
                app.CarinTLCLamp.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TrafficIntersectionControlLabel = uilabel(app.UIFigure);
            app.TrafficIntersectionControlLabel.Text = 'Traffic Intersection Control';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegularControlTab = uitab(app.TabGroup);
            app.RegularControlTab.Title = 'Regular Control';
            app.StartButton = uibutton(app.RegularControlTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RegularControlTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ScenarioSelectionButtonGroup = uibuttongroup(app.RegularControlTab);
            app.ScenarioSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScenarioSelectionButtonGroupSelectionChanged, true);
            app.ScenarioSelectionButtonGroup.Title = 'Scenario Selection';
            app.ScenarioAButton = uitogglebutton(app.ScenarioSelectionButtonGroup);
            app.ScenarioAButton.Text = 'Scenario A';
            app.ScenarioAButton.Value = true;
            app.ScenarioBButton = uitogglebutton(app.ScenarioSelectionButtonGroup);
            app.ScenarioBButton.Text = 'Scenario B';
            app.StatusLabel = uilabel(app.RegularControlTab);
            app.StatusLabel.Text = 'Initialized...';
            app.DiagnosticTab = uitab(app.TabGroup);
            app.DiagnosticTab.Title = 'Diagnostic';
            app.StartButton_2 = uibutton(app.DiagnosticTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.DiagnosticTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.SpeedButtonGroup = uibuttongroup(app.DiagnosticTab);
            app.SpeedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpeedButtonGroupSelectionChanged, true);
            app.SpeedButtonGroup.Title = 'Speed';
            app.speed_1x = uiradiobutton(app.SpeedButtonGroup);
            app.speed_1x.Text = '1X';
            app.speed_1x.Value = true;
            app.speed_3x = uiradiobutton(app.SpeedButtonGroup);
            app.speed_3x.Text = '3X';
            app.speed_5x = uiradiobutton(app.SpeedButtonGroup);
            app.speed_5x.Text = '5X';
            app.TrafficLightsSwitchLabel = uilabel(app.DiagnosticTab);
            app.TrafficLightsSwitchLabel.Text = 'Traffic Lights';
            app.TrafficLightsSwitch = uiswitch(app.DiagnosticTab, 'rocker');
            app.TrafficLightsSwitch.ValueChangedFcn = createCallbackFcn(app, @TrafficLightsSwitchValueChanged, true);
            app.TrafficLightsSwitch.Value = 'On';
            app.CrosswalksSwitchLabel = uilabel(app.DiagnosticTab);
            app.CrosswalksSwitchLabel.Text = 'Crosswalks';
            app.CrosswalksSwitch = uiswitch(app.DiagnosticTab, 'rocker');
            app.CrosswalksSwitch.ValueChangedFcn = createCallbackFcn(app, @CrosswalksSwitchValueChanged, true);
            app.CrosswalksSwitch.Value = 'On';
            app.AmbientLightSwitchLabel = uilabel(app.DiagnosticTab);
            app.AmbientLightSwitchLabel.Text = 'Ambient Light';
            app.AmbientLightSwitch = uiswitch(app.DiagnosticTab, 'rocker');
            app.AmbientLightSwitch.ValueChangedFcn = createCallbackFcn(app, @AmbientLightSwitchValueChanged, true);
            app.AmbientLightSwitch.Value = 'On';
            app.TurnLanesSwitchLabel = uilabel(app.DiagnosticTab);
            app.TurnLanesSwitchLabel.Text = 'TurnLanes';
            app.TurnLanesSwitch = uiswitch(app.DiagnosticTab, 'rocker');
            app.TurnLanesSwitch.ValueChangedFcn = createCallbackFcn(app, @TurnLanesSwitchValueChanged, true);
            app.TurnLanesSwitch.Value = 'On';
            app.TimeEditFieldLabel = uilabel(app.DiagnosticTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeField = uieditfield(app.DiagnosticTab, 'numeric');
            app.TimeField.RoundFractionalValues = 'on';
            app.TimeField.ValueDisplayFormat = '%.0f';
            app.TimeField.Editable = 'off';
            app.SensorsPanel = uipanel(app.DiagnosticTab);
            app.SensorsPanel.Title = 'Sensors';
            app.CarinTLALampLabel = uilabel(app.SensorsPanel);
            app.CarinTLALampLabel.Text = 'Car in TLA?';
            app.CarinTLALamp = uilamp(app.SensorsPanel);
            app.CarinTLCLampLabel = uilabel(app.SensorsPanel);
            app.CarinTLCLampLabel.Text = 'Car in TLC?';
            app.CarinTLCLamp = uilamp(app.SensorsPanel);
            app.NSCWPressLampLabel = uilabel(app.SensorsPanel);
            app.NSCWPressLampLabel.Text = 'NS CW Press?';
            app.NSCWPressLamp = uilamp(app.SensorsPanel);
            app.EWCWPressLampLabel = uilabel(app.SensorsPanel);
            app.EWCWPressLampLabel.Text = 'EW CW Press?';
            app.EWCWPressLamp = uilamp(app.SensorsPanel);
            app.LightIndicatorsPanel = uipanel(app.DiagnosticTab);
            app.LightIndicatorsPanel.Title = 'Light Indicators';
            app.LightALampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightALampLabel.Text = 'Light A';
            app.LightALamp = uilamp(app.LightIndicatorsPanel);
            app.LightBLampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightBLampLabel.Text = 'Light B';
            app.LightBLamp = uilamp(app.LightIndicatorsPanel);
            app.LightCLampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightCLampLabel.Text = 'Light C';
            app.LightCLamp = uilamp(app.LightIndicatorsPanel);
            app.LightDLampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightDLampLabel.Text = 'Light D';
            app.LightDLamp = uilamp(app.LightIndicatorsPanel);
            app.LightELampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightELampLabel.Text = 'Light E';
            app.LightELamp = uilamp(app.LightIndicatorsPanel);
            app.NSCWLabel = uilabel(app.LightIndicatorsPanel);
            app.NSCWLabel.Text = 'NS CW';
            app.NSCrosswalkStatus = uilamp(app.LightIndicatorsPanel);
            app.EWCWLabel = uilabel(app.LightIndicatorsPanel);
            app.EWCWLabel.Text = 'EW CW';
            app.EWCrosswalkStatus = uilamp(app.LightIndicatorsPanel);
            app.LuxEditFieldLabel = uilabel(app.DiagnosticTab);
            app.LuxEditFieldLabel.Text = 'Lux';
            app.LuxField = uieditfield(app.DiagnosticTab, 'numeric');
            app.LuxField.Editable = 'off';
            app.CrosswalkActivationStatusPanel = uipanel(app.DiagnosticTab);
            app.CrosswalkActivationStatusPanel.Title = 'Crosswalk Activation Status';
            app.NSCWActivatedLampLabel = uilabel(app.CrosswalkActivationStatusPanel);
            app.NSCWActivatedLampLabel.Text = 'NS CW - Activated';
            app.NSCWActivatedLamp = uilamp(app.CrosswalkActivationStatusPanel);
            app.EWCWActivatedLampLabel = uilabel(app.CrosswalkActivationStatusPanel);
            app.EWCWActivatedLampLabel.Text = 'EW CW - Activated';
            app.EWCWActivatedLamp = uilamp(app.CrosswalkActivationStatusPanel);
            app.ScenarioSelectionButtonGroup_2 = uibuttongroup(app.DiagnosticTab);
            app.ScenarioSelectionButtonGroup_2.Title = 'Scenario Selection';
            app.ScenarioAButton_2 = uitogglebutton(app.ScenarioSelectionButtonGroup_2);
            app.ScenarioAButton_2.Text = 'Scenario A';
            app.ScenarioAButton_2.Value = true;
            app.ScenarioBButton_2 = uitogglebutton(app.ScenarioSelectionButtonGroup_2);
            app.ScenarioBButton_2.Text = 'Scenario B';
            app.DayNightLampLabel = uilabel(app.DiagnosticTab);
            app.DayNightLampLabel.Text = 'Day / Night';
            app.DayNightLamp = uilamp(app.DiagnosticTab);
            app.StatusLabel_2 = uilabel(app.DiagnosticTab);
            app.StatusLabel_2.Text = 'Initialized...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = intersectionGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end